classdef interfaz0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TE2046101AD2024Label   matlab.ui.control.Label
        OscarOrtizTorresYonathanRomeroAmador2024Label  matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        HolaMcPolloLabel       matlab.ui.control.Label
        FrasedeactivacinLabel  matlab.ui.control.Label
        GrabarButton           matlab.ui.control.Button
        Label                  matlab.ui.control.Label
        Label_3                matlab.ui.control.Label
        Image                  matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function prediction = func(app)
            clc
        clear
        
        load trainedClassifier.mat
        load m.mat
        load s.mat
        
        % se graban 5 segundos de audio
        recObj = audiorecorder;
        
        %app.Label_2.Text="Grabando ..."; 
        recordblocking(recObj, 3);
        %app.Label_2.Text="Fin de la grabación.";
        
        % reproducción de la grabación
        play(recObj);
        
        % se obtiene el arreglo de la grabación
        prueba = getaudiodata(recObj);
        
        
        %[prueba] = audioread("C:\Users\oscar\OneDrive\Escritorio\sp_seniales\datos\emiliano\olguin1.wav");
        
        %%%
        
        fs = 8000;
        windowLength = round(0.03*fs);
        overlapLength = round(0.025*fs);
        afe = audioFeatureExtractor(SampleRate=fs, ...
            Window=hamming(windowLength,"periodic"),OverlapLength=overlapLength, ...
            zerocrossrate=true,shortTimeEnergy=true,pitch=true,mfcc=true);
        
        featureMap = info(afe);
        
        features = [];
        energyThreshold = 0.65;
        zcrThreshold = 0.45;
        
        keepLen = round(length(prueba)/3);
        
        dsInfo=8000;
        
        % Take the first portion of each recording to speed up code
        prueba = prueba(1:keepLen);
        
        feat = extract(afe,prueba);
        isSpeech = feat(:,featureMap.shortTimeEnergy) > energyThreshold;
        isVoiced = feat(:,featureMap.zerocrossrate) < zcrThreshold;
        
        voicedSpeech = isSpeech & isVoiced;
        
        feat(~voicedSpeech,:) = [];
        feat(:,[featureMap.zerocrossrate,featureMap.shortTimeEnergy]) = [];
        
        features = [features;feat];
        
        %M = mean(features,1);
        %S = std(features,[],1);
        features = (features-M)./S;
        
        prediction0=predict(trainedClassifier,features);
        prediction = mode(prediction0);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GrabarButton
        function ButtonPushed(app, event)
            app.GrabarButton.Enable="off";
            app.Label_2.Text="Grabando...";
            persona = func(app);
            app.Label_2.Text=" ";
            if((persona=="adrian")||(persona=="alfredo")||(persona=="bruno")||(persona=="daniel")||(persona=="elias")||(persona=="emiliano")||(persona=="oscar")||(persona=="paulo")||(persona=="yonathan"))
                app.Label_3.Text="Bienvenido";
                if(persona=="adrian")
                    app.Label.Text="Adrián";
                elseif(persona=="alfredo")
                    app.Label.Text="Alfredo";
                elseif(persona=="bruno")
                    app.Label.Text="Bruno";
                elseif(persona=="daniel")
                    app.Label.Text="Dano";
                elseif(persona=="elias")
                    app.Label.Text="Elías";
                elseif(persona=="emiliano")
                    app.Label.Text="Olguín";
                elseif(persona=="oscar")
                    app.Label.Text="Oscar";
                elseif(persona=="paulo")
                    app.Label.Text="JP";
                elseif(persona=="yonathan")
                    app.Label.Text="Yona";
                end
            elseif((persona=="itzel")||(persona=="mariam")||(persona=="paola")||(persona=="yestli"))
                app.Label_3.Text="Bienvenida";
                if(persona=="itzel")
                    app.Label.Text="Itzel";
                elseif(persona=="mariam")
                    app.Label.Text="Mariam";
                elseif(persona=="paola")
                    app.Label.Text="Paola";
                elseif(persona=="yestli")
                    app.Label.Text="Yestli";
                end
            end
            app.Image.ImageSource="C:\Users\oscar\OneDrive\Escritorio\sp_seniales\unlock.png";
            pause(5);
            app.Image.ImageSource="C:\Users\oscar\OneDrive\Escritorio\sp_seniales\lock.png";
            app.Label_3.Text=" ";
            app.Label.Text=" ";
            app.GrabarButton.Enable="on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'lock.png');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.GrabarButton = uibutton(app.UIFigure, 'push');
            app.GrabarButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.GrabarButton.Text = 'Grabar';
            app.FrasedeactivacinLabel = uilabel(app.UIFigure);
            app.FrasedeactivacinLabel.Text = 'Frase de activación';
            app.HolaMcPolloLabel = uilabel(app.UIFigure);
            app.HolaMcPolloLabel.Text = 'Hola, McPollo';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.OscarOrtizTorresYonathanRomeroAmador2024Label = uilabel(app.UIFigure);
            app.OscarOrtizTorresYonathanRomeroAmador2024Label.Text = 'Oscar Ortiz Torres & Yonathan Romero Amador, 2024';
            app.TE2046101AD2024Label = uilabel(app.UIFigure);
            app.TE2046101AD2024Label.Text = 'TE2046.101 | AD2024';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end